classdef Crosscorrelation_PS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        ToggleTool                      matlab.ui.container.toolbar.ToggleTool
        GridLayout                      matlab.ui.container.GridLayout
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        WindowIdxLabel                  matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        ModSigSizeLabel                 matlab.ui.control.Label
        SampleSizeValueLabel            matlab.ui.control.Label
        SampleRateValueLabel            matlab.ui.control.Label
        SampleSizeLabel                 matlab.ui.control.Label
        SampleRateLabel                 matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Ch3Button                       matlab.ui.control.RadioButton
        Ch2Button                       matlab.ui.control.RadioButton
        Ch1Button                       matlab.ui.control.RadioButton
        CorrelateButton                 matlab.ui.control.Button
        LossdBSpinner                   matlab.ui.control.Spinner
        LossdBSpinnerLabel              matlab.ui.control.Label
        NoisedBSpinner                  matlab.ui.control.Spinner
        NoisedBSpinnerLabel             matlab.ui.control.Label
        SampleWindowSlider              matlab.ui.control.RangeSlider
        SampleWindowSliderLabel         matlab.ui.control.Label
        DelayUnitSpinner                matlab.ui.control.Spinner
        DelayUnitSpinnerLabel           matlab.ui.control.Label
        BaseSignalListBox               matlab.ui.control.ListBox
        BaseSignalLabel                 matlab.ui.control.Label
        CrossCorrelationSimulatorLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

% Need to implement channel handling, need 3 channel for location
% Need saving data /screenshot option
% Channel options are off, primarly use ch1
% need envelop signal and static noise
% Version 0.9
% New Features branch
% extra notes


    properties (Access = private)
        Sig % Signal Data from wav file
        Fs % Sample Rate from wav file
        Mod_Sig % modified signal
        Noisy_Sig % generated noise according to the signal amplitude
        Sig_rows % signal rows
        Sig_cols % signal columns
        Sig_window % Windowed signal
        Mod_Sig_window % Window Modified signal
        LowerWindowLimit; % signal window limits
        UpperWindowLimit;
        Active_Ch_number; % selected channel of the wav file
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_signal(app,[]); % just initialization at start up
            app.SampleWindowSlider.MajorTickLabels = app.SampleWindowSlider.MajorTicks + "%"; % "%" to the windowslide bar, looks better
            addpath("C:\Users\peter\Desktop\Honours Project\Simulation"); % if the audio files are in different libraries
            app.ToggleTool.State = "on"; %sound on
            
        end

        % Callback function: BaseSignalListBox, BaseSignalListBox
        function load_signal(app, event)
            [app.Sig, app.Fs] = audioread(app.BaseSignalListBox.Value); % according to the list value, which is a file name for easy use
            [app.Sig_rows,app.Sig_cols] = size(app.Sig);  % checking the size of the signal
            app.SampleRateValueLabel.Text = num2str(app.Fs) + " Hertz"; % prints out the sampling frequency
            app.SampleSizeValueLabel.Text = num2str(app.Sig_rows) + " x " + num2str(app.Sig_cols) + " x " + class(app.Sig); % prints out the size of the signal
            app.SampleRateValueLabel.Visible = "on"; % turning the labels visible
            app.SampleSizeValueLabel.Visible = "on";
            app.DelayUnitSpinner.Limits = [0, app.Sig_rows-1]; % Delay cannot be higher than the signal length
            app.Active_Ch_number = 1; % tracking the active channel
            app.Ch1Button.Value = true; % default setting of the channel toggle buttons
            app.Ch2Button.Value = false;
            app.Ch3Button.Value = false;

            % For the feature channel implementation, not used at the
            % moment
            % switch app.Sig_cols         % according to the cols, determines the channel number and make the push buttons visible, need more work on this
            %     case 1
            %         app.Ch1Button.Visible = "off";
            %         app.Ch2Button.Visible = "off";
            %         app.Ch3Button.Visible = "off";
            %     case 2
            %         app.Ch1Button.Visible = "on";
            %         app.Ch2Button.Visible = "on";
            %         app.Ch3Button.Visible = "off";
            %     case 3
            %         app.Ch1Button.Visible = "on";
            %         app.Ch2Button.Visible = "on";
            %         app.Ch3Button.Visible = "on";
            %     otherwise
            %         app.Ch1Button.Visible = "off";
            %         app.Ch2Button.Visible = "off";
            %         app.Ch3Button.Visible = "off";
            % 
            % end
            plot(app.UIAxes,app.Sig);  % plot the original signal and clears the other two
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1;
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100) + 1;
            xline(app.UIAxes, app.LowerWindowLimit); % needs the line according to the values
            xline(app.UIAxes, app.UpperWindowLimit);

        end

        % Double-clicked callback: BaseSignalListBox
        function play_signal(app, event)
            if (app.Sig_cols < 3)   % sound function only able to play one and two channel wav files. 
                if (app.ToggleTool.State == "on") % sounds only, if the sound toggle button is on
                    sound(app.Sig,app.Fs);
                end
            end
        end

        % Callback function: CorrelateButton, UIAxes3
        function correlation(app, event)
            %cla(app.UIAxes3,"reset"); % clears the previous plot at axe and labels as well unfortunately. does not require.
            % wrong order cause opposite signed value in xcorr !!!
            [cor,lags]=xcorr(app.Noisy_Sig(:,app.Active_Ch_number), app.Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,app.Active_Ch_number)); % correlations calculations for channel one, need to be parameterized
            [~,idx] = max(abs(cor)); % index of the maximum value of the correlation    
            plot(app.UIAxes3,lags,cor); % plot the correlations result, need more work on this, where the max, labels, more visualization, etc...
            strcorx = "Lag: " + lags(idx); % some correlation number information at the side
            strcory = "Coeff: " + round(cor(idx)); %just want to see some roundy numbers
            app.XLabel.Text = strcorx; % turning on the lag and coeff labels
            app.XLabel.Visible = "on";
            app.YLabel.Text = strcory;
            app.YLabel.Visible = "on";

        end

        % Value changed function: SampleWindowSlider
        function windowlimits(app, event)
            % This caused the problem with the window function
            % app.LowerWindowLimit = value(1);
            % app.UpperWindowLimit = value(2);
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1;
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100) + 1;
            % maximum can reach the minimum and can cause problem, need
            % solution for it
        end

        % Value changing function: SampleWindowSlider
        function movingline(app, event)
            changingValue = event.Value;
            app.LowerWindowLimit = round(app.Sig_rows * changingValue(1) / 100) + 1; %need to use the event.Value, otherwise the app. properties does not update in time
            app.UpperWindowLimit = round(app.Sig_rows * changingValue(2) / 100) + 1;
            % noise addition to the original signal
            % noiseamplitude = (max(app.Sig(:)))*exp(app.NoisedBSpinner.Value/20);
            % app.Noisy_Sig = app.Sig + noiseamplitude*rand(size(app.Sig));
            cla(app.UIAxes); % if using the slides it recovers the original signal for windowing
            plot(app.UIAxes,app.Sig);
            xline(app.UIAxes, app.LowerWindowLimit); % Two vertical line to visualize the window range
            xline(app.UIAxes, app.UpperWindowLimit);
            % extra information about the range of the window
            windowsizetext = "Window Index: " + num2str(app.LowerWindowLimit-1) + " - " + num2str(app.UpperWindowLimit-1) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            app.WindowIdxLabel.Visible = "on";


        end

        % Button pushed function: AnalyzeButton
        function analyze(app, event)
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1; %need to recalculate the limits to the actual base signal
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100) + 1;
            z = zeros(abs(app.DelayUnitSpinner.Value),app.Sig_cols);
            [zrows,zcols] = size(z);
            if (app.DelayUnitSpinner.Value>0)
                delayedsig = app.Sig(1:end-zrows,1:zcols);
                app.Mod_Sig = [z; delayedsig];
            elseif (app.DelayUnitSpinner.Value<0) %this is not implemented yet, if the lag is negative it is become and advance
                advancesig = app.Sig(1:end-zrows-1,1:zcols);
                app.Mod_Sig = [advancesig,z];
            else
                app.Mod_Sig = app.Sig; % when no lag
            end
            app.CorrelateButton.Visible = "on";% Start button become Visible
            [mrows,mcols] = size(app.Mod_Sig);  % Modified signal size, just double check it has the same size
            sizetext = "Mod. Sig. Size: " + num2str(mrows) + " x " + num2str(mcols) + " x " + class(app.Mod_Sig);
            app.ModSigSizeLabel.Text = sizetext;
            app.ModSigSizeLabel.Visible = "on";
            windowsizetext = "Window Index: " + num2str(app.LowerWindowLimit-1) + " - " + num2str(app.UpperWindowLimit-1) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            app.WindowIdxLabel.Visible = "on";
            plot(app.UIAxes2,(app.LowerWindowLimit:app.UpperWindowLimit-1),app.Mod_Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,:));
            if (app.ToggleTool.State == "on") %only plays sound if the toggle button are on
                sound(app.Mod_Sig(app.LowerWindowLimit:app.UpperWindowLimit-1), app.Fs);
            end
            % noise addition to the original signal
            noiseamplitude = (max(max(app.Sig)))*10^(app.NoisedBSpinner.Value/20); % noise amplitude
            lossyfactor = 10^(app.LossdBSpinner.Value/20); % attenuation
            % Mod_sig is delayed and can be atteunated and add noise to it
            app.Noisy_Sig = app.Mod_Sig/lossyfactor + noiseamplitude*rand(size(app.Sig));
            cla(app.UIAxes);
            plot(app.UIAxes,app.Noisy_Sig);
            xline(app.UIAxes, app.LowerWindowLimit); % Two vertical line to visualize the window range refresher
            xline(app.UIAxes, app.UpperWindowLimit);
        end

        % Callback function: ToggleTool
        function soundtoggle(app, event)
            % Icon change for controlling a sound. After a time it is
            % really annoying
            if (app.ToggleTool.State == "on")
                app.ToggleTool.Icon ="soundon.jpg";
            else
                app.ToggleTool.Icon ="soundoff.jpg";
            end
        end

        % Off callback: ToggleTool
        function soundoff(app, event)
            clear sound; % stopping the sound with a toggle button
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Tooltip = {'Sound On/Off'};
            app.ToggleTool.ClickedCallback = createCallbackFcn(app, @soundtoggle, true);
            app.ToggleTool.Icon = fullfile(pathToMLAPP, 'soundon.jpg');
            app.ToggleTool.OffCallback = createCallbackFcn(app, @soundoff, true);
            app.ToggleTool.State = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Cross-Correlation')
            xlabel(app.UIAxes3, 'Lag')
            ylabel(app.UIAxes3, 'Correlation Coefficient')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @correlation, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Modulated Signal')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Signal')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.CrossCorrelationSimulatorLabel = uilabel(app.GridLayout);
            app.CrossCorrelationSimulatorLabel.Interpreter = 'latex';
            app.CrossCorrelationSimulatorLabel.Text = 'Cross-Correlation Simulator';
            app.BaseSignalLabel = uilabel(app.GridLayout);
            app.BaseSignalLabel.Text = 'Base Signal';
            app.BaseSignalListBox = uilistbox(app.GridLayout);
            app.BaseSignalListBox.Items = {'Bird_Song.wav', 'Cuckoo.wav', 'Chirp.wav', 'Speech.wav', 'Custom.wav'};
            app.BaseSignalListBox.ValueChangedFcn = createCallbackFcn(app, @load_signal, true);
            app.BaseSignalListBox.ClickedFcn = createCallbackFcn(app, @load_signal, true);
            app.BaseSignalListBox.DoubleClickedFcn = createCallbackFcn(app, @play_signal, true);
            app.BaseSignalListBox.Value = 'Bird_Song.wav';
            app.DelayUnitSpinnerLabel = uilabel(app.GridLayout);
            app.DelayUnitSpinnerLabel.Text = 'Delay (Unit)';
            app.DelayUnitSpinner = uispinner(app.GridLayout);
            app.DelayUnitSpinner.Limits = [0 30000];
            app.SampleWindowSliderLabel = uilabel(app.GridLayout);
            app.SampleWindowSliderLabel.Text = 'Sample Window';
            app.SampleWindowSlider = uislider(app.GridLayout, 'range');
            app.SampleWindowSlider.ValueChangedFcn = createCallbackFcn(app, @windowlimits, true);
            app.SampleWindowSlider.ValueChangingFcn = createCallbackFcn(app, @movingline, true);
            app.NoisedBSpinnerLabel = uilabel(app.GridLayout);
            app.NoisedBSpinnerLabel.Text = 'Noise (dB)';
            app.NoisedBSpinner = uispinner(app.GridLayout);
            app.NoisedBSpinner.Limits = [-80 100];
            app.NoisedBSpinner.Value = -80;
            app.LossdBSpinnerLabel = uilabel(app.GridLayout);
            app.LossdBSpinnerLabel.Text = 'Loss (dB)';
            app.LossdBSpinner = uispinner(app.GridLayout);
            app.LossdBSpinner.Limits = [0 20];
            app.CorrelateButton = uibutton(app.GridLayout, 'push');
            app.CorrelateButton.ButtonPushedFcn = createCallbackFcn(app, @correlation, true);
            app.CorrelateButton.Visible = 'off';
            app.CorrelateButton.Text = 'Correlate';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.Ch1Button = uiradiobutton(app.ButtonGroup);
            app.Ch1Button.Visible = 'off';
            app.Ch1Button.Text = 'Ch1';
            app.Ch1Button.Value = true;
            app.Ch2Button = uiradiobutton(app.ButtonGroup);
            app.Ch2Button.Visible = 'off';
            app.Ch2Button.Text = 'Ch2';
            app.Ch3Button = uiradiobutton(app.ButtonGroup);
            app.Ch3Button.Visible = 'off';
            app.Ch3Button.Text = 'Ch3';
            app.SampleRateLabel = uilabel(app.GridLayout);
            app.SampleRateLabel.Text = 'Sample Rate:';
            app.SampleSizeLabel = uilabel(app.GridLayout);
            app.SampleSizeLabel.Text = 'Sample Size:';
            app.SampleRateValueLabel = uilabel(app.GridLayout);
            app.SampleRateValueLabel.Visible = 'off';
            app.SampleRateValueLabel.Text = 'Sample Rate Value';
            app.SampleSizeValueLabel = uilabel(app.GridLayout);
            app.SampleSizeValueLabel.Visible = 'off';
            app.SampleSizeValueLabel.Text = 'Sample Size Value';
            app.ModSigSizeLabel = uilabel(app.GridLayout);
            app.ModSigSizeLabel.Text = 'Mod. Sig. Size:';
            app.AnalyzeButton = uibutton(app.GridLayout, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @analyze, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.WindowIdxLabel = uilabel(app.GridLayout);
            app.WindowIdxLabel.Text = 'Window Index:';
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Visible = 'off';
            app.YLabel.Text = 'Y:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crosscorrelation_PS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end